classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        q1SliderLabel                   matlab.ui.control.Label
        q1Slider                        matlab.ui.control.Slider
        q2SliderLabel                   matlab.ui.control.Label
        q2Slider                        matlab.ui.control.Slider
        q3SliderLabel                   matlab.ui.control.Label
        q3Slider                        matlab.ui.control.Slider
        q5SliderLabel                   matlab.ui.control.Label
        q5Slider                        matlab.ui.control.Slider
        XSliderLabel                    matlab.ui.control.Label
        XSlider                         matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        ZSlider                         matlab.ui.control.Slider
        SelectShapetoDrawListBoxLabel   matlab.ui.control.Label
        SelectShapetoDrawListBox        matlab.ui.control.ListBox
        PerspexonFrameSwitchLabel       matlab.ui.control.Label
        PerspexonFrameSwitch            matlab.ui.control.Switch
        TEACHJOINTSLabel                matlab.ui.control.Label
        TEACHJOYSTICKSLabel             matlab.ui.control.Label
        ScaleOfShapeSliderLabel         matlab.ui.control.Label
        ScaleOfShapeSlider              matlab.ui.control.Slider
        CollisionModeSwitchLabel        matlab.ui.control.Label
        CollisionModeSwitch             matlab.ui.control.Switch
        STARTButton                     matlab.ui.control.Button
        SELECTMODESLabel                matlab.ui.control.Label
        SELECTSHAPELabel                matlab.ui.control.Label
        STARTSIMULATIONLabel            matlab.ui.control.Label
        PLOTSIMButton                   matlab.ui.control.Button
        PLOTSIMULATIONLabel             matlab.ui.control.Label
        TESTdobot_qButton               matlab.ui.control.Button
        PaperAngleKnobLabel             matlab.ui.control.Label
        PaperAngleKnob                  matlab.ui.control.Knob
        REPLACEPAPERButton              matlab.ui.control.Button
        TeachModeSwitchLabel            matlab.ui.control.Label
        TeachModeSwitch                 matlab.ui.control.Switch
        SelectTeachTypeLabel            matlab.ui.control.Label
        SelectTeachTypeListBox          matlab.ui.control.ListBox
        YawSliderLabel                  matlab.ui.control.Label
        YawSlider                       matlab.ui.control.Slider
        EMERGENCYSTOPButton             matlab.ui.control.StateButton
        CollisionCubeYValueSliderLabel  matlab.ui.control.Label
        CollisionCubeYValueSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        dobot; % Dobot model
        environment; % Environment model
        pencil; % Pencil Model
        paper; % Paper model
        
        % Environment base transforms
        dobotBase = transl(0,0,0);
        paperBase;
        paperBaseXValue = 0.2875;
        pencilBase = transl(0,-0.25,0.06);
    end
    

    methods (Access = private)

        % Value changed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            fprintf('Emergency Stop has been pressed \n \n');
        end

        % Button pushed function: PLOTSIMButton
        function PLOTSIMButtonPushed(app, event)
            fprintf('Mode selected: \n');
            fprintf('The Perspex is: %s \n',app.PerspexonFrameSwitch.Value);
            fprintf('Collision mode is: %s \n',app.CollisionModeSwitch.Value);
            fprintf('Teach mode is: %s \n \n',app.TeachModeSwitch.Value);
            
            % Update paper base transform
            app.paperBase = transl(app.paperBaseXValue,0,0)*rpy2tr(0,0,deg2rad(app.PaperAngleKnob.Value));
            
            % Determine if perspex should be plotted
            if app.PerspexonFrameSwitch.Value == "Off"
                perspexOn = false;
            else
                perspexOn = true;
            end
            
            % Plot dobot and environment
            app.dobot = Dobot(app.dobotBase);
            app.environment = Environment(app.pencilBase, perspexOn);
            app.paper = Paper(app.paperBase);
            app.pencil = Pencil(app.pencilBase);
            axis equal;
        end

        % Button pushed function: TESTdobot_qButton
        function TESTdobot_qButtonPushed(app, event)
            fprintf('Testing dobot_q\n');
            
            dobot = Dobot(transl(0,0,0));
            load('dobot_q.mat');
            dobot.model.animate(dobot.CalcJointAngles(dobot_q));
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fprintf('Start Button Pressed\n\n');
%             while true
%                 app.dobot.test(app)
%                 pause(5);
%             end
            
            if app.CollisionModeSwitch.Value == "Off" && app.TeachModeSwitch.Value == "Off"
                % Pick up Pencil
                fprintf('Picking Up Pencil\n\n');
                app.dobot.PickPencil(app.pencil, app);
                
                fprintf('Centralising Pencil Over Paper\n\n');
                app.dobot.CentrePencilOverPaper(app.paper, app.pencil, app);
                
                fprintf('Calculating Edges of Shape\n\n');
                app.dobot.planCornersOfShape(app.SelectShapetoDrawListBox.Value, app.ScaleOfShapeSlider.Value);
                
                fprintf('Drawing Shape\n\n');
                app.dobot.DrawShapeOnPaper(app.pencil, app);
                
                fprintf('Return Pencil\n\n');
                app.dobot.ReturnPencil(app.pencil, app);
            elseif app.CollisionModeSwitch.Value == "On" && app.TeachModeSwitch.Value == "Off"
                fprintf('Moving Until just before Collision with object\n')
                app.dobot.CollisionMode(app.CollisionCubeYValueSlider.Value, app);
                fprintf('Collision Detection Complete. \nClose Simulation to Continue\n\n');
            else
                fprintf('Teach Mode, use sliders to teach robot\nTeach using %s\n\n', app.SelectTeachTypeListBox.Value)
                while app.TeachModeSwitch.Value == "On"
                    app.dobot.TeachMode(app.SelectTeachTypeListBox.Value, app.q1Slider.Value, app.q2Slider.Value, app.q3Slider.Value, app.q5Slider.Value, app.XSlider.Value, app.YSlider.Value, app.ZSlider.Value, app.YawSlider.Value, app);
                end
            end
        end

        % Button pushed function: REPLACEPAPERButton
        function REPLACEPAPERButtonPushed(app, event)
            % Remove paper and any lines drawn
            app.paper.RemovePaper();
            try delete(app.dobot.lines); end;
            % Replace with new sheet of paper
            app.paperBase = transl(app.paperBaseXValue,0,0)*rpy2tr(0,0,deg2rad(app.PaperAngleKnob.Value));
            app.paper = Paper(app.paperBase);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-90 90];
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [0 85];
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-10 95];
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-90 90];
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-1 1];
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-1 1];
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-1 1];
            app.SelectShapetoDrawListBoxLabel = uilabel(app.UIFigure);
            app.SelectShapetoDrawListBoxLabel.Text = {'Select Shape'; 'to Draw'};
            app.SelectShapetoDrawListBox = uilistbox(app.UIFigure);
            app.SelectShapetoDrawListBox.Items = {'Square', 'Triangle'};
            app.SelectShapetoDrawListBox.Value = 'Square';
            app.PerspexonFrameSwitchLabel = uilabel(app.UIFigure);
            app.PerspexonFrameSwitchLabel.Text = 'Perspex on Frame';
            app.PerspexonFrameSwitch = uiswitch(app.UIFigure, 'slider');
            app.TEACHJOINTSLabel = uilabel(app.UIFigure);
            app.TEACHJOINTSLabel.Text = 'TEACH JOINTS';
            app.TEACHJOYSTICKSLabel = uilabel(app.UIFigure);
            app.TEACHJOYSTICKSLabel.Text = 'TEACH JOYSTICKS';
            app.ScaleOfShapeSliderLabel = uilabel(app.UIFigure);
            app.ScaleOfShapeSliderLabel.Text = {'Scale Of'; 'Shape'};
            app.ScaleOfShapeSlider = uislider(app.UIFigure);
            app.ScaleOfShapeSlider.Limits = [1 2];
            app.ScaleOfShapeSlider.Value = 1;
            app.CollisionModeSwitchLabel = uilabel(app.UIFigure);
            app.CollisionModeSwitchLabel.Text = 'Collision Mode';
            app.CollisionModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SELECTMODESLabel = uilabel(app.UIFigure);
            app.SELECTMODESLabel.Text = '1) SELECT  MODE''S';
            app.SELECTSHAPELabel = uilabel(app.UIFigure);
            app.SELECTSHAPELabel.Text = '3) SELECT  SHAPE';
            app.STARTSIMULATIONLabel = uilabel(app.UIFigure);
            app.STARTSIMULATIONLabel.Text = '4) START SIMULATION';
            app.PLOTSIMButton = uibutton(app.UIFigure, 'push');
            app.PLOTSIMButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTSIMButtonPushed, true);
            app.PLOTSIMButton.Text = 'PLOT SIM';
            app.PLOTSIMULATIONLabel = uilabel(app.UIFigure);
            app.PLOTSIMULATIONLabel.Text = '2) PLOT SIMULATION';
            app.TESTdobot_qButton = uibutton(app.UIFigure, 'push');
            app.TESTdobot_qButton.ButtonPushedFcn = createCallbackFcn(app, @TESTdobot_qButtonPushed, true);
            app.TESTdobot_qButton.Text = 'TEST dobot_q';
            app.PaperAngleKnobLabel = uilabel(app.UIFigure);
            app.PaperAngleKnobLabel.Text = 'Paper Angle';
            app.PaperAngleKnob = uiknob(app.UIFigure, 'continuous');
            app.PaperAngleKnob.Limits = [-45 45];
            app.REPLACEPAPERButton = uibutton(app.UIFigure, 'push');
            app.REPLACEPAPERButton.ButtonPushedFcn = createCallbackFcn(app, @REPLACEPAPERButtonPushed, true);
            app.REPLACEPAPERButton.Text = 'REPLACE PAPER';
            app.TeachModeSwitchLabel = uilabel(app.UIFigure);
            app.TeachModeSwitchLabel.Text = 'Teach Mode';
            app.TeachModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.SelectTeachTypeLabel = uilabel(app.UIFigure);
            app.SelectTeachTypeLabel.Text = {'Select Teach'; 'Type'};
            app.SelectTeachTypeListBox = uilistbox(app.UIFigure);
            app.SelectTeachTypeListBox.Items = {'Joints', 'Joystick'};
            app.SelectTeachTypeListBox.Value = 'Joints';
            app.YawSliderLabel = uilabel(app.UIFigure);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.UIFigure);
            app.YawSlider.Limits = [-1 1];
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'state');
            app.EMERGENCYSTOPButton.ValueChangedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.CollisionCubeYValueSliderLabel = uilabel(app.UIFigure);
            app.CollisionCubeYValueSliderLabel.Text = {'Collision'; 'Cube'; 'Y Value'};
            app.CollisionCubeYValueSlider = uislider(app.UIFigure);
            app.CollisionCubeYValueSlider.Limits = [-0.25 0.25];
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end